classdef robot_ABB_IRB_2400 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        VelocidadeslinealesdelefectorfinalLabel  matlab.ui.control.Label
        VelocidadesarticulareseneltiempoLabel  matlab.ui.control.Label
        PosicionesarticulareseneltiempoLabel  matlab.ui.control.Label
        PlotTrayectoriaButton     matlab.ui.control.Button
        ConfigurationButtonGroup  matlab.ui.container.ButtonGroup
        cButton                   matlab.ui.control.RadioButton
        Button_2                  matlab.ui.control.RadioButton
        Button                    matlab.ui.control.RadioButton
        ViewButtonGroup           matlab.ui.container.ButtonGroup
        XYButton_2                matlab.ui.control.RadioButton
        YZButton                  matlab.ui.control.RadioButton
        XZButton                  matlab.ui.control.RadioButton
        DButton                   matlab.ui.control.RadioButton
        BLamp                     matlab.ui.control.Lamp
        NextPosCalButton          matlab.ui.control.StateButton
        NoteEditField             matlab.ui.control.EditField
        NoteEditFieldLabel        matlab.ui.control.Label
        PressEscLabel             matlab.ui.control.Label
        PressrLabel               matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        ExitButton                matlab.ui.control.Button
        RollEditField             matlab.ui.control.NumericEditField
        RollEditFieldLabel        matlab.ui.control.Label
        PitchEditField            matlab.ui.control.NumericEditField
        PitchEditFieldLabel       matlab.ui.control.Label
        YawEditField              matlab.ui.control.NumericEditField
        YawEditFieldLabel         matlab.ui.control.Label
        ZmmEditField              matlab.ui.control.NumericEditField
        ZmmEditFieldLabel         matlab.ui.control.Label
        YmmEditField              matlab.ui.control.NumericEditField
        YmmEditFieldLabel         matlab.ui.control.Label
        XmmEditField              matlab.ui.control.NumericEditField
        XmmEditFieldLabel         matlab.ui.control.Label
        Q6SliderLabel             matlab.ui.control.Label
        Q6Slider                  matlab.ui.control.Slider
        Q6EditField               matlab.ui.control.NumericEditField
        Q6EditFieldLabel          matlab.ui.control.Label
        Q5SliderLabel             matlab.ui.control.Label
        Q5Slider                  matlab.ui.control.Slider
        Q5EditField               matlab.ui.control.NumericEditField
        Q5EditFieldLabel          matlab.ui.control.Label
        Q4SliderLabel             matlab.ui.control.Label
        Q4Slider                  matlab.ui.control.Slider
        Q4EditField               matlab.ui.control.NumericEditField
        Q4EditFieldLabel          matlab.ui.control.Label
        Q3SliderLabel             matlab.ui.control.Label
        Q3Slider                  matlab.ui.control.Slider
        Q3EditField               matlab.ui.control.NumericEditField
        Q3EditFieldLabel          matlab.ui.control.Label
        Q2SliderLabel             matlab.ui.control.Label
        Q2Slider                  matlab.ui.control.Slider
        Q2EditField               matlab.ui.control.NumericEditField
        Q2EditFieldLabel          matlab.ui.control.Label
        Q1EditField               matlab.ui.control.NumericEditField
        Q1EditFieldLabel          matlab.ui.control.Label
        InterfazGrficaRobotABBIRB2400Label  matlab.ui.control.Label
        Q1SliderLabel             matlab.ui.control.Label
        Q1Slider                  matlab.ui.control.Slider
        axesvlin                  matlab.ui.control.UIAxes
        axesdq6                   matlab.ui.control.UIAxes
        axesdq5                   matlab.ui.control.UIAxes
        axesdq4                   matlab.ui.control.UIAxes
        axesdq3                   matlab.ui.control.UIAxes
        axesdq2                   matlab.ui.control.UIAxes
        axesdq1                   matlab.ui.control.UIAxes
        axesq6                    matlab.ui.control.UIAxes
        axesq5                    matlab.ui.control.UIAxes
        axesq4                    matlab.ui.control.UIAxes
        axesq3                    matlab.ui.control.UIAxes
        axesq2                    matlab.ui.control.UIAxes
        axesq1                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        q = zeros(1,6); % Guarda valores articulares de q1 - q6
        L0 = 6.15*0.1;  
        L1 = 1.00*0.1;
        L2 = 7.05*0.1;
        L3 = 1.35*0.1;
        L4 = 7.55*0.1;
        %L5 = 0.85*0.1;
        L5 = 0;
        robotabb ; 
        %qlimits = [-180 180; -100 110; -60 65; -200 200; -120 120; -400 400];
        qlimits = [-180 180; -111 101; -66 61; -200 200; -120 120; -400 400]; %q2 y q3 son negativos por la convención que asignamos
        %qlimits = [-180 180; -110 100; -65 60; -200 200; -120 120; -400 400]; %q2 y q3 son negativos por la convención que asignamos
        ws = [-2.5 2.5 -2.5 2.5 -1.6 3];
        cal_points = [855 1455; 360 2041; 541 693; 1351 -118; 400 -302; -1350 624; -53 1036];
        qs_pos_conf1 = [pi/4 -pi/6 pi/3 0 pi/4 pi/2; 3*pi/4 -pi/3 pi/12 pi/6 pi/3 pi/4];
        qs_pos_conf2 = [-pi/4 pi/3 pi/6 pi/3 0 -3*pi/4; 2*pi/3 pi/2 pi/4 3*pi/4 -pi/3 pi/2];
        aux = 1;
        f1;
        f2;
        zoom_ne = -1;
    end
    
    % métodos adiconales
    methods (Access = private)   
        function v_round = round_vec(app, vec)
            v_round = vec;
            for i = 1:max(size(vec))
                if abs(v_round(i)) < 0.00001
                    v_round(i) = 0;
                end
            end
        end
        
        function [] = clear_qs_labels(app)
            app.Q1EditFieldLabel.Visible = 'off';
            app.Q2EditFieldLabel.Visible = 'off';
            app.Q3EditFieldLabel.Visible = 'off';
            app.Q4EditFieldLabel.Visible = 'off';
            app.Q5EditFieldLabel.Visible = 'off';
            app.Q6EditFieldLabel.Visible = 'off';
        end
        
        function [] = update_limits(app)
            % Actualiza los límites de los campos numéricos y deslizadores
            % de q1-q6
            app.Q1Slider.Limits = app.qlimits(1,:);
            app.Q2Slider.Limits = app.qlimits(2,:);
            app.Q3Slider.Limits = app.qlimits(3,:);
            app.Q4Slider.Limits = app.qlimits(4,:);
            app.Q5Slider.Limits = app.qlimits(5,:);
            app.Q6Slider.Limits = app.qlimits(6,:);
            
            app.Q1EditField.Limits = app.qlimits(1,:);
            app.Q2EditField.Limits = app.qlimits(2,:);
            app.Q3EditField.Limits = app.qlimits(3,:);
            app.Q4EditField.Limits = app.qlimits(4,:);
            app.Q5EditField.Limits = app.qlimits(5,:);
            app.Q6EditField.Limits = app.qlimits(6,:);
        end
        
        function [] = update_pos(app)
            % Función actualiza los valores de la pose del efector final
            % x, y, z, roll, pitch y yaw
            T = app.robotabb.fkine(app.q);
            xyz = round(transl(T),3)*1000;
            rpy = round(rad2deg(tr2rpy(T)),4);
            app.XmmEditField.Value = xyz(1);
            app.YmmEditField.Value = xyz(2);
            app.ZmmEditField.Value = xyz(3);
            app.RollEditField.Value = double(rpy(1));
            app.PitchEditField.Value = double(rpy(2));
            app.YawEditField.Value = double(rpy(3));
        end
        
        function [] = update_qs(app)
            % Actualiza los valores de los campos numérico sde q1-q6
            qs = rad2deg(app.q);
            qs = app.round_vec(qs);
            qs = round(qs,4);
            app.Q1EditField.Value = qs(1);
            app.Q2EditField.Value = qs(2);
            app.Q3EditField.Value = qs(3);
            app.Q4EditField.Value = qs(4);
            app.Q5EditField.Value = qs(5);
            app.Q6EditField.Value = qs(6);
            app.robotabb.plot(app.q);
        end
        
        function [] = update_qs_sliders(app)
            % Actualiza los valores de los slider sde q1-q6
            qs = rad2deg(app.q);
            qs = app.round_vec(qs);
            qs = round(qs,4);
            app.Q1Slider.Value = qs(1);
            app.Q2Slider.Value = qs(2);
            app.Q3Slider.Value = qs(3);
            app.Q4Slider.Value = qs(4);
            app.Q5Slider.Value = qs(5);
            app.Q6Slider.Value = qs(6);
        end
        
        function [] = update_view(app,vista)
            % Con Texto se actualiza a la vista seleccionada en vista
            [p,r] = app.capture_pose();
            p = 0.001*p;
            switch vista
                case 'XY'                   
                    delete(app.f1);
                    delete(app.f2);
                    app.NoteEditField.Value = 'seleccionó XY';
                    view([0 0 2])
                    app.f1 = plot3([-2.5 p(1)],[p(2) p(2)],[0 0],'g--'); %horizontal
                    app.f2 = plot3([p(1) p(1)],[-2.5 p(2)],[0 0],'r--'); %vertical
                case 'XZ'
                    delete(app.f1);
                    delete(app.f2);
                    app.NoteEditField.Value = 'seleccionó XZ';
                    view([0 -2 0])
                    app.f1 = plot3([-2.5 p(1)],[0 0],[p(3) p(3)],'b--'); %horizontal
                    app.f2 = plot3([p(1) p(1)],[0 0],[-1.5 p(3)],'r--'); %vertical
                case 'YZ'
                    delete(app.f1);
                    delete(app.f2);
                    app.NoteEditField.Value = 'seleccionó YZ';
                    view([2 0 0])
                    app.f1 = plot3([0 0],[-2.5 p(2)],[p(3) p(3)],'b--'); %horizontal
                    app.f2 = plot3([0 0],[p(2) p(2)],[-1.5 p(3)],'g--'); %vertical
                case '3D'
                    delete(app.f1);
                    delete(app.f2);
                    app.NoteEditField.Value = 'seleccionó 3D';
                    view([30 30])
            end
        end
        function [] = place_graphics(app,fig)
            % Función que coloca de forma centrada la figura del robot
            % y de forma centrada el la GUI, siempre y cuando, la
            % resolución de la pantalla sea mayor a 1221x800 pixeles

            fig.Units = 'pixels';
            size_screen = get(0,'ScreenSize');%[left bottom width height]
            size_fig = [400 300];
%             if size_screen(3)>1221 && size_screen(4) > size_fig(1)
%                 api_pos = app.UIFigure.OuterPosition; %[left bottom width height]
%                 fig_pos = fig.OuterPosition;
%                 
%                 px_api = (size_screen(3)-size_fig(1)-api_pos(3))*0.5;
%                 py_api = (size_screen(4)-api_pos(4))*0.5;                
%                 app.UIFigure.Position = [px_api py_api api_pos(3) api_pos(4)];
%                 
%                 px_fig = px_api+api_pos(3)+10;
%                 py_fig = (size_screen(4)-fig_pos(4))*0.5;
%                 fig.Position = [px_fig py_fig size_fig(1) size_fig(2)];
%             else
%                 disp('Resolución pequeña')
%                 fig.Position = [400 0 800 600];
%                 app.UIFigure.Position = [0 0 421  574];
%             end
            app.UIFigure.Position = [20 10 app.UIFigure.Position(3:4)];
            fig.Position = [1000 20 400 300];
            fig.Visible = 'on';
            app.UIFigure.Visible = 'on';
        end
    
        function [p, r] = capture_pose(app)
            % Retorna la pose actual del efector final (posición y
            % orientación en las cajas de texto X,Y,Z,Roll,Pitch, Yaw,
            % retornando por separados el vector de posición y el de
            % orientación.
            p = [app.XmmEditField.Value app.YmmEditField.Value ...
                app.ZmmEditField.Value];
            r = [app.RollEditField.Value app.PitchEditField.Value ...
                app.YawEditField.Value];
        end
        
        function [] = closeapp(app)
            close all
            clc
            delete(app.UIFigure)
        end
        
        function [] = resetear(app)
            % Pasa el robot a home, actualizando las cajas de texto a sus
            % valores por defecto, así mismo pasa con los sliders, y se
            % vuelve a cargar la figura a su estado por defecto.
            
            app.aux = 1;
            
            app.q = zeros(1,6);
            
            app.Q1EditField.Value = 0;
            app.Q2EditField.Value = 0;
            app.Q3EditField.Value = 0;
            app.Q4EditField.Value = 0;
            app.Q5EditField.Value = 0;
            app.Q6EditField.Value = 0;
            
            app.Q1Slider.Value = 0;
            app.Q2Slider.Value = 0;
            app.Q3Slider.Value = 0;
            app.Q4Slider.Value = 0;
            app.Q5Slider.Value = 0;
            app.Q6Slider.Value = 0;
            
            app.NoteEditField.Value = '';
            app.BLamp.Color = 'green';
            app.robotabb.plot(app.q);
            app.update_pos();
            app.update_qs();
            app.update_qs_sliders();
            app.clean_plot();
            
            app.ViewButtonGroup.SelectedObject = app.DButton;
            app.ConfigurationButtonGroup.SelectedObject = app.Button;
        end
        
        function [qislim] = q_is_in_limits(app,qs)
            % Función para validar si el valor de qi está dentro de los
            % límites articulares
            s = '';
            for i = 1:max(size(qs))
                val = rad2deg(qs(i));
                s1 = val < max(app.qlimits(i,:));
                s2 = val > min(app.qlimits(i,:));
                disp([s1 s2 val app.qlimits(i,:)])
                if and(s1, s2)
                    disp('')
                else
                    s = strcat(s,'q',num2str(i),' ');
                end
            end
            qislim = s;
        end
        
        function [] = up_ikinematics_cal(app)
            % calcula la cinemática inversa para q1, q2 y q3 para las
            % posiciones de calibración del datasheet
            % corregir q1
            [p, r] = app.capture_pose();
            p = 0.001*p; %se pasa a metros la posición
            
            codo = 1;%codo arriba: +1, codo abajo: -1
            adelante = 1; % adelante: +1, atras: -1
            contador = 0;
            l1 = app.L0;
            l2 = app.L1;
            l3 = app.L2;
            l4 = sqrt((app.L3)^2+(app.L4)^2);
            xm = p(1); ym = p(2); zm = p(3);
            
            % Cálculo de q1
            q1 = atan2(ym,abs(xm));
            
            % Cálculo de q2
            l2 = l2*cos(q1);
            vpw = [xm zm] - [l2 l1];
            pw = norm(vpw);
            xp = vpw(1);
            zp = vpw(2);
            
            ca = ( (l3^2)+(pw^2)-(l4^2) ) / (2*l3*pw); %cos(\alpha)
            sa = sqrt(1-(ca^2)); %sin(\alpha)
            if abs(ca) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q2';
                a = 0;
                contador = contador + 1;
            else
                a = atan2(sa,ca);
            end
            
            b = atan2(-xp,zp);
            q2 = a + b;
            
            % Cálculo de q3
            cg = ((l3^2)+(l4^2)-(pw^2)) / (2*l3*l4); % cos(\gamma)
            sg = sqrt(1-(cg^2)); % sin(\gamma)
            
            if abs(cg) > 1
                app.NoteEditField.Value = 'Fuera de los límites articulares de q3';
                g = 0;
                contador = contador + 1;
            else
                g = atan2(sg,cg);
            end
            
            t = atan2(app.L3,app.L4); % \theta            
            q3 = g - (t + pi/2);
            
            app.q(1:3) = [q1 q2 q3];
            if contador == 0 && app.q_is_in_limits()
                app.update_qs();
                app.BLamp.Color = 'green';
            else
                app.BLamp.Color = 'red';
            end
        end

        function [qs] = get_ikine_conf1(app,T0ef)
            l1 = app.L0;
            l2 = app.L1;
            l3 = app.L2;
            l4 = sqrt((app.L3)^2+(app.L4)^2);
            l5 = 0.085; 
            [Ref,pef]=tr2rt(T0ef);
            
            %Posición muñeca
            pm=pef-l5*Ref(:,3);
            
            %q1
            q1=atan2(pm(2),pm(1));
            q1d=rad2deg(q1);
            
            pw=sqrt((pm(1)-l2*cos(q1))^2+(pm(2)-l2*sin(q1))^2+(pm(3)-l1)^2);
            
            %alpha
            ca=(l3^2+pw^2-l4^2)/(2*l3*pw);
            if abs(ca)>1
                qs = '';
                return
            end
            sa1=sqrt(1-ca^2);
            sa2=-sa1;
            a=atan2(sa1,ca);
            % a=atan2(sa2,ca);
            
            %beta
            sb=(pm(3)-l1)/pw;
            if abs(sb)>1
                qs = '';
                return
            end
            cb1=sqrt(1-sb^2);
            cb2=-cb1;
            b=atan2(sb,cb1);
            % b=atan2(sb,cb2);
            
            %q2
            q2=-pi/2+a+b;
            q2d=rad2deg(q2);
            
            %gamma
            cg=(l3^2+l4^2-pw^2)/(2*l3*l4);
            if abs(cg)>1
                qs = '';
                return
            end
            sg1=sqrt(1-cg^2);
            sg2=-sg1;
            g=atan2(sg1,cg);
            %g=atan2(sg2,cg);
            
            %theta
            theta=atan(1.35/7.55);
            
            %q3
            q3=g-pi/2-theta;
            q3d=rad2deg(q3);
            
            %Orientación del efector final
            R06=Ref;
            [R03,~]=tr2rt(app.robotabb.A([1 2 3],[q1 q2 q3]));
            R36=R03\R06;
     
            %q4
            q4=atan2(R36(3,3),R36(1,3));
            
            %q5
            q5=atan2(cos(q4)*R36(1,3)+sin(q4)*R36(3,3),-R36(2,3));
            
            %q6
            q6=atan2(-sin(q4)*R36(1,1)+cos(q4)*R36(3,1),-sin(q4)*R36(1,2)+cos(q4)*R36(3,2));
            
            qs = [q1 q2 q3 q4 q5 q6];
        end
    
        function [qs] = get_ikine_conf2(app,T0ef)
            l1 = app.L0;
            l2 = app.L1;
            l3 = app.L2;
            l4 = sqrt((app.L3)^2+(app.L4)^2);
            l5 = 0.085; 
            
            [Ref,pef]=tr2rt(T0ef);

            %Posición muñeca
            pm=pef-l5*Ref(1:3,3);
            
            %delta
            d=atan2(pm(2),pm(1));
            disp(rad2deg(d))
            %q1
            q1=d-pi;
            q1d=rad2deg(q1);
            
            pw=sqrt((pm(1)-l2*cos(q1))^2+(pm(2)-l2*sin(q1))^2+(pm(3)-l1)^2);
            
            %alpha
            ca=(l3^2+pw^2-l4^2)/(2*l3*pw);
            sa1=sqrt(1-ca^2);
            sa2=-sa1;
            a=atan2(sa1,ca);
            % a=atan2(sa2,ca);
            
            %beta
            cb=(pm(3)-l1)/pw;
            sb1=sqrt(1-cb^2);
            sb2=-sb1;
            b=atan2(sb1,cb);
            % b=atan2(sb2,cb);
            
            %q2
            q2=a+b;
            q2d=rad2deg(q2);
            
            %gamma
            cg=(l3^2+l4^2-pw^2)/(2*l3*l4);
            sg1=sqrt(1-cg^2);
            sg2=-sg1;
            g=atan2(sg1,cg);
            %g=atan2(sg2,cg);
            
            %theta
            theta=atan(1.35/7.55);
            
            %q3
            q3=g-pi/2-theta;
            q3d=rad2deg(q3);
            
            %Orientación del efector final
            R06=Ref;
            [R03,~]=tr2rt(app.robotabb.A([1 2 3],[q1 q2 q3]));
            R36=R03\R06;
            %q4
            q4=atan2(R36(3,3),R36(1,3));
            
            %q5
            q5=atan2(cos(q4)*R36(1,3)+sin(q4)*R36(3,3),-R36(2,3));
            
            %q6
            q6=atan2(-sin(q4)*R36(1,1)+cos(q4)*R36(3,1),-sin(q4)*R36(1,2)+cos(q4)*R36(3,2));
            
            qs = [q1 q2 q3 q4 q5 q6];
        end
        
        function [] = clean_plot(app)
            % Limpia la figura, y se vuelve a colocar el robot en la misma
            clf
            fig = gcf;
            fig.Visible = 'off';
            app.robotabb.plot(app.q)                    
            hold on
            trplot(eye(4),'rgb','thick',2,'frame','O','length',0.3)
            app.place_graphics(fig)   
            view([30 30])
            fig.Visible = 'on';
        end
        
        
        function [] = up_kinematics(app)
            % TODO: Revisar porqué los valores de q4-q6 cambian cundo solo
            % se cambia la posición
            disp('------------------------------------------------------')
            disp('Límites')
            disp(app.qlimits')
            [p, r] = app.capture_pose();
            p = 0.001*p;
            Toef = transl(p(1), p(2), p(3))*trotx(r(1),'deg')*troty(r(2),'deg')*trotz(r(3),'deg');
            disp('Qs antes')
            disp(rad2deg(app.q))
            qss = app.get_ikine_conf1(Toef);
            
            
            if isempty(qss)
                app.BLamp.Color = 'red';
                app.NoteEditField.Value = strcat('Punto fuera de alcance');
            else
                outlim = app.q_is_in_limits(qss);
                if isempty(outlim)
                    disp('Qs después')
                    disp(rad2deg(qss))
                    app.q = qss;
                    app.update_qs();
                    app.update_qs_sliders();
                    app.robotabb.plot(app.q);
                    app.update_view(app.ViewButtonGroup.SelectedObject.Text);
                    app.BLamp.Color = 'green';
                    T = app.robotabb.fkine(app.q);
                    v = round([transl(T)' tr2rpy(T,'deg')],2);
                    disp(v)
                    s = strcat('[ ',num2str(v(1)),', ',num2str(v(2)),', ',num2str(v(3)) ...
                        ,'] ',' - ', '[',num2str(v(4)),', ',num2str(v(5)),', ',num2str(v(6)),']');
                    app.NoteEditField.Value = num2str(s);
                else
                    app.BLamp.Color = 'red';
                    app.NoteEditField.Value = strcat('Fuera de límites: ',outlim);
                end
            end
        end
        
        function [q] = ikinemIRB(app,T)
            robot = app.robotabb;
            [Ref,pef] = tr2rt(T);
        
            %% Parámetros del manipulador - dm
            l1=6.15*0.1;
            l2=1.00*0.1;
            l3=7.05*0.1;
            l4=sqrt(.135^2+.755^2);
            l5=0.085;
            
            %% Posición del efector final
            %posición de la muñeca
            pm = pef-l5*Ref(1:3,3); 
            
            %% q1
            q1 = atan2(pm(2),pm(1));
            
            %% q2
            pw = sqrt((pm(1)-l2*cos(q1))^2+(pm(2)-l2*sin(q1))^2+(pm(3)-l1)^2);
        
            %alpha
            ca = (l3^2+pw^2-l4^2)/(2*l3*pw);
            sa1 = sqrt(1-ca^2);
            sa2 = -sa1;
            a = atan2(sa1,ca);
            % a=atan2(sa2,ca);
            
            %beta
            sb = (pm(3)-l1)/pw;
            cb1 = sqrt(1-sb^2);
            cb2 = -cb1;
            b = atan2(sb,cb1);
            % b=atan2(sb,cb2);
            
            %q2
            q2 = -pi/2+a+b;
        
            %% q3
            %gamma
            cg = (l3^2+l4^2-pw^2)/(2*l3*l4);
            sg1 = sqrt(1-cg^2);
            sg2 = -sg1;
            g = atan2(sg1,cg);
            %g=atan2(sg2,cg);
            
            %theta
            theta = atan(1.35/7.55);
            
            q3 = g-pi/2-theta;
        
            %% Orientación del efector final
            R06 = Ref;
            [R03,~] = tr2rt(robot.A([1 2 3],[q1 q2 q3]));
            R36 = R03\R06;
            
            %q4
            q4 = atan2(R36(3,3),R36(1,3));
        
            %q5
            q5 = atan2(cos(q4)*R36(1,3)+sin(q4)*R36(3,3),-R36(2,3));
        
            %q6
            q6 = atan2(-sin(q4)*R36(1,1)+cos(q4)*R36(3,1),-sin(q4)*R36(1,2)+cos(q4)*R36(3,2));
        
            %% Vector de variables de articulación
            q = [q1 q2 q3 q4 q5 q6];
        end
        
        function [dt, t_tray, x_tray, y_tray, z_tray] = get_trajectories(app)
            
            ah=1.55; % alcance horizontal máximo (dm)
            L=ah*0.4; 
            
            % Tramo 1
            t_t1 = linspace(0,0.2,5)';
            [x_t1,v_t1,a_t1] = poly3([0 0 -1.5 -5],t_t1);
            y_t1 = 7.5*ones(length(t_t1),1);
            z_t1 = 10*ones(length(t_t1),1);
            
            % Tramo 2
            v_t2 = -5; %Velocidad crucero
            t_t2f = 1.1/abs(v_t2);
            
            dt = t_t1(2)-t_t1(1);
            t_t2 = (dt:dt:t_t2f)';
            
            x_t2 = x_t1(length(x_t1)) + v_t2*t_t2;
            y_t2 = 7.5*ones(length(t_t2),1);
            z_t2 = 10*ones(length(t_t2),1);
            
            % Tramo 3
            w_t3 = v_t2/0.5;
            t_t3f = abs((-pi+pi/2)/10);
            
            t_t3 = (dt:dt:t_t3f)';
            
            theta_3 = -pi/2+w_t3*t_t3;
            x_t3 = x_t2(length(x_t2))+0.5*cos(theta_3);
            y_t3 = 7.5*ones(length(t_t3),1);
            z_t3 = 10.5+0.5*sin(theta_3);
            
            % Tramo 4
            % v_t2 = -5; %Velocidad crucero
            v_t4 = -v_t2;
            t_t4f = 1.55/v_t4;
            
            % dt = t_t1(2)-t_t1(1);
            t_t4 = (dt:dt:t_t4f)';
            
            x_t4 = -3.1*ones(length(t_t4),1);
            y_t4 = 7.5*ones(length(t_t4),1);
            z_t4 = z_t3(length(z_t3)) + v_t4*t_t4;
            
            % Tramo 5
            % w_t3 = v_t2/0.5;
            t_t5f = abs((-3*pi/2+pi)/10);
            
            t_t5 = (dt:dt:t_t5f)';
            
            theta_5 = -pi+w_t3*t_t5;
            x_t5 = x_t4(length(x_t4))+0.5*(1+cos(theta_5));
            y_t5 = 7.5*ones(length(t_t5),1);
            z_t5 = z_t4(length(z_t4))+0.5*sin(theta_5);
            
            % Tramo 6
            % v_t2 = -5; %Velocidad crucero
            v_t6 = -v_t2;
            t_t6f = 5.2/v_t6;
            
            % dt = t_t1(2)-t_t1(1);
            t_t6 = (dt:dt:t_t6f+dt)';
            
            x_t6 = x_t5(length(x_t5)) + v_t6*t_t6;
            y_t6 = 7.5*ones(length(t_t6),1);
            z_t6 = z_t5(length(z_t5))*ones(length(t_t6),1);
            
            % Tramo 7
            % w_t3 = v_t2/0.5;
            t_t7f = abs((-2*pi+3*pi/2)/10);
            
            t_t7 = (dt:dt:t_t7f)';
            
            theta_7 = -3*pi/2+w_t3*t_t5;
            x_t7 = x_t6(length(x_t6))+0.5*cos(theta_7);
            y_t7 = 7.5*ones(length(t_t5),1);
            z_t7 = z_t6(length(z_t6))-0.5*(1-sin(theta_7));
            
            % Tramo 8
            % v_t2 = -5; %Velocidad crucero
            v_t8 = v_t2;
            t_t8f = abs(1.55/v_t8);
            
            % dt = t_t1(2)-t_t1(1);
            t_t8 = (dt:dt:t_t8f)';
            
            x_t8 = 3.1*ones(length(t_t4),1);
            y_t8 = 7.5*ones(length(t_t4),1);
            z_t8 = z_t7(length(z_t7)) + v_t8*t_t8;
            
            % Tramo 9
            % w_t3 = v_t2/0.5;
            t_t9f = abs((-pi/2)/10);
            
            t_t9 = (dt:dt:t_t9f)';
            
            theta_9 = w_t3*t_t9;
            x_t9 = x_t8(length(x_t8))-0.5*(1-cos(theta_9));
            y_t9 = 7.5*ones(length(t_t5),1);
            z_t9 = z_t8(length(z_t8))+0.5*sin(theta_9);
            
            % Tramo 10
            % v_t2 = -5; %Velocidad crucero
            v_t10 = v_t2;
            t_t10f = abs(1.1/v_t10);
            
            % dt = t_t1(2)-t_t1(1);
            t_t10 = (dt:dt:t_t10f)';
            
            x_t10 = x_t9(length(x_t9)) + v_t10*t_t10;
            y_t10 = 7.5*ones(length(t_t10),1);
            z_t10 = 10*ones(length(t_t10),1);
            
            % Tramo 11
            t_t11 = (dt:dt:t_t1(length(t_t1)))';
            [x_t11,v_t11,a_t11] = poly3([1.5 -5 0 0],t_t11);
            y_t11 = 7.5*ones(length(t_t11),1);
            z_t11 = 10*ones(length(t_t11),1);
            
            % Gráfica de trayectoria
            taux = [t_t1;t_t2;t_t3;t_t4;t_t5;t_t6;t_t7;t_t8;t_t9;t_t10;t_t11];
            
            t_tray = (0:dt:dt*(length(taux)-1))';
            x_tray = [x_t1';x_t2;x_t3;x_t4;x_t5;x_t6;x_t7;x_t8;x_t9;x_t10;x_t11'];
            y_tray = [y_t1;y_t2;y_t3;y_t4;y_t5;y_t6;y_t7;y_t8;y_t9;y_t10;y_t11];
            z_tray = [z_t1;z_t2;z_t3;z_t4;z_t5;z_t6;z_t7;z_t8;z_t9;z_t10;z_t11];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Función de configruación de inicio, se establecen los límites
            % en los deslizadores, se crea el objeto del robot
            app.UIFigure.Visible = 'off';
            update_limits(app)
            app.clear_qs_labels();
            
            L(1) = Link('revolute','alpha',     0,'a',     0,'d',app.L0,'offset',0,'modified');
            L(2) = Link('revolute','alpha',  pi/2,'a',app.L1,'d',     0,'offset',pi/2,'modified');
            L(3) = Link('revolute','alpha',     0,'a',app.L2,'d',     0,'offset',0,'modified');
            L(4) = Link('revolute','alpha',  pi/2,'a',app.L3,'d',app.L4,'offset',0,'modified');
            L(5) = Link('revolute','alpha', -pi/2,'a',     0,'d',     0,'offset',0,'modified');
            L(6) = Link('revolute','alpha',  pi/2,'a',     0,'d',app.L5,'offset',0,'modified');
            
            
            plot_options = {'workspace',app.ws,'scale',.4};%,'view',[30 30],'tilesize',2,'ortho', 'lightpos',[2 2 10] };
            app.robotabb = SerialLink(L,'name','ABB IRB2400','plotopt',plot_options);
            app.robotabb.tool = transl(0,0,0.085);
            
            fig = figure('Name','ROBOT: ABB IRB 2400','NumberTitle','off', ...
                'Position',[200 0 800 600],'visible','off');
            
            app.robotabb.plot(app.q)
            hold on
            trplot(eye(4),'rgb','thick',2,'frame','O','length',0.3)
            view([30 30])
            xlabel('X [m]');
            ylabel('Y [m]');
            zlabel('Z [m]');
            update_pos(app)            
            place_graphics(app,fig)
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.closeapp();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetear();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % captura teclado
            % r: resetear valores en la app
            % escape: cerrar la app
            key = event.Key;
            switch key
                case 'escape'
                    app.closeapp()
                case 'r'
                    app.resetear()
            end
        end

        % Value changed function: Q1EditField
        function Q1EditFieldValueChanged(app, event)
            app.q(1) = deg2rad(app.Q1EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q2EditField
        function Q2EditFieldValueChanged(app, event)
            app.q(2) = deg2rad(app.Q2EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q3EditField
        function Q3EditFieldValueChanged(app, event)
            app.q(3) = deg2rad(app.Q3EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q4EditField
        function Q4EditFieldValueChanged(app, event)
            app.q(4) = deg2rad(app.Q4EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q5EditField
        function Q5EditFieldValueChanged(app, event)
            app.q(5) = deg2rad(app.Q5EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Callback function
        function Q6EditFieldValueChanged(app, event)
            app.q(6) = deg2rad(app.Q6EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            val = event.Value;
            app.q(1) = deg2rad(val);
            app.Q1EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            val = event.Value;
            app.q(2) = deg2rad(val);
            app.Q2EditField.Value = round(val,2);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            val = event.Value;
            app.q(3) = deg2rad(val);
            app.Q3EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            val = event.Value;
            app.q(4) = deg2rad(val);
            app.Q4EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            val = event.Value;
            app.q(5) = deg2rad(val);
            app.Q5EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            val = event.Value;
            app.q(6) = deg2rad(val);
            app.Q6EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changed function: XmmEditField
        function XmmEditFieldValueChanged(app, event)
            disp('colocar CI')
            app.up_kinematics();
        end

        % Value changed function: YmmEditField
        function YmmEditFieldValueChanged(app, event)
            app.up_kinematics();
        end

        % Value changed function: ZmmEditField
        function ZmmEditFieldValueChanged(app, event)
            app.up_kinematics();
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            app.up_kinematics();
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            app.up_kinematics();
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            app.up_kinematics();
        end

        % Selection changed function: ViewButtonGroup
        function ViewButtonGroupSelectionChanged(app, event)
            % Permite ver las vistas normales a los planos XY, YZ y YZ
            selectedButton = app.ViewButtonGroup.SelectedObject;
            view = selectedButton.Text;            
            app.update_view(view);
        end

        % Value changed function: NextPosCalButton
        function NextPosCalButtonValueChanged(app, event)
            % usa las posiciones definidas para la configuración 1,
            % configuración 2 y configuración de calibración (puntos del
            % datasheet), por el momento la configuración por excelencia es
            % la 1
            
            % TODO: actualizar captura de la pose desde la app.
            value = app.NextPosCalButton.Value;
            delete(app.f1)
            delete(app.f2)
            conf = app.ConfigurationButtonGroup.SelectedObject.Text;
            if app.aux + 1 > 2 && conf ~= 'c'
                app.aux = 1;
            elseif app.aux + 1 > max(size(app.cal_points)) && conf == 'c'
                app.aux = 1;
            else
                app.aux = app.aux + 1;
            end
            switch conf
                case '1'
                    Toef = app.robotabb.fkine(app.qs_pos_conf1(app.aux,:));
                    %Toef = transl(0.5, 0.1, 1)*trotx(30,'deg')*troty(60,'deg')*trotz(10,'deg');
                    app.q = app.get_ikine_conf1(Toef);
                    %app.NoteEditField.Value = 'Configuración 1 seleccionada';
                    disp(app.qlimits')
                    disp('Configuración 1 seleccionada')
                    disp(rad2deg(app.qs_pos_conf1(app.aux,:)))
                case '2'
                    Toef = app.robotabb.fkine(app.qs_pos_conf2(app.aux,:));
                    app.q = app.get_ikine_conf2(Toef);
                    if rad2deg(app.q(1)) < -180
                        app.q(1) = 2*pi+app.q(1);
                    end
                    %app.NoteEditField.Value = 'Configuración 2 seleccionada';
                    disp(app.qlimits')
                    disp('Configuración 2 seleccionada')
                    disp('qs entrantes')
                    disp(rad2deg(app.qs_pos_conf2(app.aux,:)))     
                case 'c'
                    %app.NoteEditField.Value = 'Configuración calibración seleccionada';
                    disp('Configuración calibración seleccionada') 
                    disp(app.qlimits')
                    app.XmmEditField.Value = app.cal_points(app.aux,1);
                    app.ZmmEditField.Value = app.cal_points(app.aux,2);
                    app.YmmEditField.Value = 0;
                    app.q = zeros(size(app.q));
                    app.robotabb.tool = eye(4);
                    app.up_ikinematics_cal()
                    disp('qs antes')
                    disp(rad2deg(app.q))
                    %app.q = deg2rad(round(rad2deg(app.q)));
            end

            disp('qs obtenidos')
            disp(rad2deg(app.q))
            
            app.update_pos();
            app.update_qs();
            app.update_qs_sliders();
            app.robotabb.plot(app.q);
            app.update_view(app.ViewButtonGroup.SelectedObject.Text);
            app.robotabb.tool = transl(0,0,0.085);
           
        end

        % Selection changed function: ConfigurationButtonGroup
        function ConfigurationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConfigurationButtonGroup.SelectedObject;
            app.aux = 1;    
        end

        % Button pushed function: PlotTrayectoriaButton
        function PlotTrayectoriaButtonPushed(app, event)
            app.NoteEditField.Value = 'Realizando cálculos preliminares';
            app.BLamp.Color = '#4DBEEE';
            [dt, t_tray, x_tray, y_tray, z_tray] = app.get_trajectories();
            x_tray= x_tray*0.1; y_tray= y_tray*0.1; z_tray = z_tray*0.1;
            
            % Cinemática inversa y obtención de perfiles para variables de articulación
            path = [y_tray x_tray z_tray];
            
            p_cent = [0 0.75 (max(z_tray)-min(z_tray))+min(z_tray)];
            path_cent = path-repmat(p_cent,size(path,1),1);
            
            R_cent = angvec2r(-pi/4,[0 1 0]);
            
            path = zeros(size(path_cent));            
            
            for i = 1:length(path_cent)
                path(i,:) = R_cent*path_cent(i,:)'+p_cent';
            end           
            
            via = zeros(length(path),6);
            [R,~] = tr2rt(app.robotabb.fkine([0 0 pi/4 0 0 0]));
            %R = R*rotx(pi/4);
            for i = 1:length(path)
                T = [R path(i,:)';0 0 0 1];
                qs = app.ikinemIRB(T);
                via(i,:) = qs;
            end
            v_via = diff(via,1,1)/dt;
            t_tray_v = t_tray(1:length(t_tray)-1);
            
            axes_vlim = deg2rad([150 150 150 360 360 450]); % rad/s - Vel. máximas de articulación

            via_interp = mstraj(via,axes_vlim,[],via(1,:),dt,0.02); % perfiles interpolados
            
            
            v_via_interp = diff(via_interp,1,1)/dt;
            v_path = zeros(size(v_via_interp,1),size(v_via_interp,2));
            t_tray_interp = (0:dt:dt*(length(via_interp)-1));
            
            for i = 1:length(v_path)-1
                J = app.robotabb.jacob0(via_interp(i,:),'rpy');
                v_path(i,:) = J*v_via_interp(i,:)';
            end

            app.NoteEditField.Value = 'Ploteando variables articulares';            
            [~,p_ec] = tr2rt(app.robotabb.fkine(via_interp(i,:)));
            path(length(path)+1,:) = p_ec;
            view(0.5*[1, 1, 2.5])
            if app.zoom_ne < 0
                zoom(2)
                app.zoom_ne = 1;
            end
            

            v_path_lin = zeros(length(v_path),1);
            for i=1:length(v_path_lin)
                v_path_lin(i) = norm(v_path(i,:));
            end
            disp('tiempo de trayectoria')
            disp([length(t_tray) length(t_tray_v)])
            disp('via v_via v_path')
            disp([length(via) length(v_via) length(v_path)])
            disp(v_via(length(v_via)))
            disp('iniciale y finales')
            for i = 1:length(via_interp)
                if i> 61
                    break
                end
                
                plot3(path(i,1), path(i,2), path(i,3),'*','color','#4DBEEE',"MarkerSize",5)
                
                plot(app.axesq1,t_tray(1:i),via(1:i,1), 'b')
                plot(app.axesq2,t_tray(1:i),via(1:i,2), 'b')
                plot(app.axesq3,t_tray(1:i),via(1:i,3), 'b')
                plot(app.axesq4,t_tray(1:i),via(1:i,4), 'b')
                plot(app.axesq5,t_tray(1:i),via(1:i,5), 'b')
                plot(app.axesq6,t_tray(1:i),via(1:i,6), 'b')
    
                plot(app.axesdq1,t_tray_v(1:i),v_via(1:i,1), 'b')
                plot(app.axesdq2,t_tray_v(1:i),v_via(1:i,2), 'b')
                plot(app.axesdq3,t_tray_v(1:i),v_via(1:i,3), 'b')
                plot(app.axesdq4,t_tray_v(1:i),v_via(1:i,4), 'b')
                plot(app.axesdq5,t_tray_v(1:i),v_via(1:i,5), 'b')
                plot(app.axesdq6,t_tray_v(1:i),v_via(1:i,6), 'b')
                
                plot(app.axesvlin, t_tray_interp(1:i),v_path(1:i,1),...
                 t_tray_interp(1:i),v_path(1:i,2),...
                 t_tray_interp(1:i),v_path(1:i,3),...
                 t_tray_interp(1:i),v_path_lin(1:i),...
                 'LineWidth',1.3);
                legend(app.axesvlin, 'v_x','v_y','v_z','v_{lin}')
                
                % TODO: optimizar actualización de infromaxión xyz, rpy,
                % sliders y campos de texto
                app.q = via_interp(i,:);
                app.update_pos();
                app.update_qs();
                app.update_qs_sliders();
                
                app.robotabb.plot(via_interp(i,:))
                
                hold on            
                %pause(0.05)
            end
            quiver3(0,0,0,1.5,0,1.5,'LineWidth',1.5,'color','#7E2F8E')
            app.BLamp.Color = 'green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.axesq1 = uiaxes(app.UIFigure);
            title(app.axesq1, 'q_1')
            xlabel(app.axesq1, 'Tiempo [s]')
            ylabel(app.axesq1, 'Posición [rad]')
            zlabel(app.axesq1, 'Z')
            app.axesq2 = uiaxes(app.UIFigure);
            title(app.axesq2, 'q3')
            xlabel(app.axesq2, 'Tiempo [s]')
            ylabel(app.axesq2, 'Posición [rad]')
            zlabel(app.axesq2, 'Z')
            app.axesq3 = uiaxes(app.UIFigure);
            title(app.axesq3, 'q4')
            xlabel(app.axesq3, 'Tiempo [s]')
            ylabel(app.axesq3, 'Posición [rad]')
            zlabel(app.axesq3, 'Z')
            app.axesq4 = uiaxes(app.UIFigure);
            title(app.axesq4, 'q_2')
            xlabel(app.axesq4, 'Tiempo [s]')
            ylabel(app.axesq4, 'Posición [rad]')
            zlabel(app.axesq4, 'Z')
            app.axesq5 = uiaxes(app.UIFigure);
            title(app.axesq5, 'q_5')
            xlabel(app.axesq5, 'Tiempo [s]')
            ylabel(app.axesq5, 'Posición [rad]')
            zlabel(app.axesq5, 'Z')
            app.axesq6 = uiaxes(app.UIFigure);
            title(app.axesq6, 'q_6')
            xlabel(app.axesq6, 'Tiempo [s]')
            ylabel(app.axesq6, 'Posición [rad]')
            zlabel(app.axesq6, 'Z')
            app.axesdq1 = uiaxes(app.UIFigure);
            title(app.axesdq1, '\omega_1')
            xlabel(app.axesdq1, 'Tiempo [s]')
            ylabel(app.axesdq1, 'Velocidad [rad/s]')
            zlabel(app.axesdq1, 'Z')
            app.axesdq2 = uiaxes(app.UIFigure);
            title(app.axesdq2, '\omega_3')
            xlabel(app.axesdq2, 'Tiempo [s]')
            ylabel(app.axesdq2, 'Velocidad [rad/s]')
            zlabel(app.axesdq2, 'Z')
            app.axesdq3 = uiaxes(app.UIFigure);
            title(app.axesdq3, '\omega_4')
            xlabel(app.axesdq3, 'Tiempo [s]')
            ylabel(app.axesdq3, 'Velocidad [rad/s]')
            zlabel(app.axesdq3, 'Z')
            app.axesdq4 = uiaxes(app.UIFigure);
            title(app.axesdq4, '\omega_2')
            xlabel(app.axesdq4, 'Tiempo [s]')
            ylabel(app.axesdq4, 'Velocidad [rad/s]')
            zlabel(app.axesdq4, 'Z')
            app.axesdq5 = uiaxes(app.UIFigure);
            title(app.axesdq5, '\omega_5')
            xlabel(app.axesdq5, 'Tiempo [s]')
            ylabel(app.axesdq5, 'Velocidad [rad/s]')
            zlabel(app.axesdq5, 'Z')
            app.axesdq6 = uiaxes(app.UIFigure);
            title(app.axesdq6, '\omega_6')
            xlabel(app.axesdq6, 'Tiempo [s]')
            ylabel(app.axesdq6, 'Velocidad [rad/s]')
            zlabel(app.axesdq6, 'Z')
            app.axesvlin = uiaxes(app.UIFigure);
            title(app.axesvlin, 'Velocidad')
            xlabel(app.axesvlin, 'Tiempo [s]')
            ylabel(app.axesvlin, 'v [m/s]')
            zlabel(app.axesvlin, 'Z')
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-180 180];
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1  ';
            app.InterfazGrficaRobotABBIRB2400Label = uilabel(app.UIFigure);
            app.InterfazGrficaRobotABBIRB2400Label.Text = {'Interfaz Gráfica'; 'Robot ABB IRB 2400'};
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField.ValueChangedFcn = createCallbackFcn(app, @Q1EditFieldValueChanged, true);
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditField.ValueChangedFcn = createCallbackFcn(app, @Q2EditFieldValueChanged, true);
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-180 180];
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q3EditFieldLabel = uilabel(app.UIFigure);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditField.ValueChangedFcn = createCallbackFcn(app, @Q3EditFieldValueChanged, true);
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-180 180];
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q4EditFieldLabel = uilabel(app.UIFigure);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q4EditField.ValueChangedFcn = createCallbackFcn(app, @Q4EditFieldValueChanged, true);
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-180 180];
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q5EditFieldLabel = uilabel(app.UIFigure);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q5EditField.ValueChangedFcn = createCallbackFcn(app, @Q5EditFieldValueChanged, true);
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-180 180];
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q6EditFieldLabel = uilabel(app.UIFigure);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.Limits = [-180 180];
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6';
            app.XmmEditFieldLabel = uilabel(app.UIFigure);
            app.XmmEditFieldLabel.Text = 'X [mm]';
            app.XmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmmEditField.ValueChangedFcn = createCallbackFcn(app, @XmmEditFieldValueChanged, true);
            app.YmmEditFieldLabel = uilabel(app.UIFigure);
            app.YmmEditFieldLabel.Text = 'Y [mm]';
            app.YmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmmEditField.ValueChangedFcn = createCallbackFcn(app, @YmmEditFieldValueChanged, true);
            app.ZmmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmmEditFieldLabel.Text = 'Z [mm]';
            app.ZmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmmEditField.ValueChangedFcn = createCallbackFcn(app, @ZmmEditFieldValueChanged, true);
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw [°]';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch [°]';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll [°]';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PressrLabel = uilabel(app.UIFigure);
            app.PressrLabel.Text = 'Press "r"';
            app.PressEscLabel = uilabel(app.UIFigure);
            app.PressEscLabel.Text = 'Press "Esc"';
            app.NoteEditFieldLabel = uilabel(app.UIFigure);
            app.NoteEditFieldLabel.Text = 'Note';
            app.NoteEditField = uieditfield(app.UIFigure, 'text');
            app.NextPosCalButton = uibutton(app.UIFigure, 'state');
            app.NextPosCalButton.ValueChangedFcn = createCallbackFcn(app, @NextPosCalButtonValueChanged, true);
            app.NextPosCalButton.Text = 'Next Pos Cal';
            app.BLamp = uilamp(app.UIFigure);
            app.ViewButtonGroup = uibuttongroup(app.UIFigure);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewButtonGroupSelectionChanged, true);
            app.ViewButtonGroup.Title = 'View';
            app.DButton = uiradiobutton(app.ViewButtonGroup);
            app.DButton.Text = '3D';
            app.DButton.Value = true;
            app.XZButton = uiradiobutton(app.ViewButtonGroup);
            app.XZButton.Text = 'XZ';
            app.YZButton = uiradiobutton(app.ViewButtonGroup);
            app.YZButton.Text = 'YZ';
            app.XYButton_2 = uiradiobutton(app.ViewButtonGroup);
            app.XYButton_2.Text = 'XY';
            app.ConfigurationButtonGroup = uibuttongroup(app.UIFigure);
            app.ConfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfigurationButtonGroupSelectionChanged, true);
            app.ConfigurationButtonGroup.Title = 'Configuration';
            app.Button = uiradiobutton(app.ConfigurationButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ConfigurationButtonGroup);
            app.Button_2.Text = '2';
            app.cButton = uiradiobutton(app.ConfigurationButtonGroup);
            app.cButton.Text = 'c';
            app.PlotTrayectoriaButton = uibutton(app.UIFigure, 'push');
            app.PlotTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrayectoriaButtonPushed, true);
            app.PlotTrayectoriaButton.Text = 'Plot Trayectoria';
            app.PosicionesarticulareseneltiempoLabel = uilabel(app.UIFigure);
            app.PosicionesarticulareseneltiempoLabel.Text = 'Posiciones articulares en el tiempo';
            app.VelocidadesarticulareseneltiempoLabel = uilabel(app.UIFigure);
            app.VelocidadesarticulareseneltiempoLabel.Text = 'Velocidades articulares en el tiempo';
            app.VelocidadeslinealesdelefectorfinalLabel = uilabel(app.UIFigure);
            app.VelocidadeslinealesdelefectorfinalLabel.Text = 'Velocidades lineales  del efector final';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_ABB_IRB_2400

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end